classdef Control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ESTOPButton           matlab.ui.control.Button
        X_VelSliderLabel      matlab.ui.control.Label
        X_VelSlider           matlab.ui.control.Slider
        q1SliderLabel         matlab.ui.control.Label
        q1Slider              matlab.ui.control.Slider
        ModeButtonGroup       matlab.ui.container.ButtonGroup
        Adv_TeachButton       matlab.ui.control.ToggleButton
        SortRedButton         matlab.ui.control.ToggleButton
        SortGreenButton       matlab.ui.control.ToggleButton
        SortBlueButton        matlab.ui.control.ToggleButton
        SortAllButton         matlab.ui.control.ToggleButton
        VisualServoButton     matlab.ui.control.ToggleButton
        q2SliderLabel         matlab.ui.control.Label
        q2Slider              matlab.ui.control.Slider
        q3SliderLabel         matlab.ui.control.Label
        q3Slider              matlab.ui.control.Slider
        q5SliderLabel         matlab.ui.control.Label
        q5Slider              matlab.ui.control.Slider
        Y_VelSliderLabel      matlab.ui.control.Label
        Y_VelSlider           matlab.ui.control.Slider
        Z_VelSliderLabel      matlab.ui.control.Label
        Z_VelSlider           matlab.ui.control.Slider
        Z_Rot_VelSliderLabel  matlab.ui.control.Label
        Z_Rot_VelSlider       matlab.ui.control.Slider
        AddObsticalButton     matlab.ui.control.Button
        ActivateCameraButton  matlab.ui.control.Button
        PauseButton           matlab.ui.control.Button
        Spinner               matlab.ui.control.Spinner
        Spinner_2             matlab.ui.control.Spinner
        Spinner_3             matlab.ui.control.Spinner
        Spinner_4             matlab.ui.control.Spinner
        Spinner_5             matlab.ui.control.Spinner
        Spinner_6             matlab.ui.control.Spinner
        Spinner_7             matlab.ui.control.Spinner
        Spinner_8             matlab.ui.control.Spinner
    end

    
    properties (Access = public)
           q1 = 0; % store qvaribles
           q2 = 0; 
           q3 = 0;
           q5 = 0;
           
           
           vx = 0; %velocity vars 
           vy = 0;
           vz = 0;
           rotVz = 0;
    end
    
    methods (Access = public)
        
        function results = readq(app)
            results = [app.q1 app.q2 app.q3 0 app.q5];
        end
    end
    
    methods (Access = public)
        
        function results = readVel(app)
            results = [app.vx app.vy app.vz app.rotVz];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: q1Slider
        function q1SliderValueChanged(app, event)
            value = app.q1Slider.Value;
            app.q1 = value;
            
           
        end

        % Value changed function: q2Slider
        function q2SliderValueChanged(app, event)
            value = app.q2Slider.Value;
            app.q2 = value;  
        end

        % Value changed function: q3Slider
        function q3SliderValueChanged(app, event)
            value = app.q3Slider.Value;
            app.q3 = value;  
        end

        % Value changed function: q5Slider
        function q5SliderValueChanged(app, event)
            value = app.q5Slider.Value;
            app.q5 = value;  
        end

        % Value changed function: X_VelSlider
        function X_VelSliderValueChanged(app, event)
            value = app.X_VelSlider.Value;
            app.vx = value;
        end

        % Value changed function: Y_VelSlider
        function Y_VelSliderValueChanged(app, event)
            value = app.Y_VelSlider.Value;
            app.vy = value;
        end

        % Value changed function: Z_VelSlider
        function Z_VelSliderValueChanged(app, event)
            value = app.Z_VelSlider.Value;
            app.vz = value;
        end

        % Value changed function: Z_Rot_VelSlider
        function Z_Rot_VelSliderValueChanged(app, event)
            value = app.Z_Rot_VelSlider.Value;
            app.rotVz = value;
        end

        % Value changed function: Spinner_5
        function Spinner_5ValueChanged(app, event)
            value = app.Spinner_5.Value;
            app.vx = value;
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            value = app.Spinner.Value;
            app.q1 = value;
        end

        % Value changed function: Spinner_2
        function Spinner_2ValueChanged(app, event)
            value = app.Spinner_2.Value;
            app.q2 = value;
        end

        % Value changed function: Spinner_3
        function Spinner_3ValueChanged(app, event)
            value = app.Spinner_3.Value;
            app.q3 = value;
        end

        % Value changed function: Spinner_4
        function Spinner_4ValueChanged(app, event)
            value = app.Spinner_4.Value;
            app.q5 = value;
        end

        % Value changed function: Spinner_6
        function Spinner_6ValueChanged(app, event)
            value = app.Spinner_6.Value;
            app.vy = value;
        end

        % Value changed function: Spinner_7
        function Spinner_7ValueChanged(app, event)
            value = app.Spinner_7.Value;
            app.vz = value;
        end

        % Value changed function: Spinner_8
        function Spinner_8ValueChanged(app, event)
            value = app.Spinner_8.Value;
            app.rotVz = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.Text = 'E - STOP';
            app.X_VelSliderLabel = uilabel(app.UIFigure);
            app.X_VelSliderLabel.Text = 'X_Vel';
            app.X_VelSlider = uislider(app.UIFigure);
            app.X_VelSlider.Limits = [-2 2];
            app.X_VelSlider.MajorTicks = [-2 -1 0 1 2];
            app.X_VelSlider.ValueChangedFcn = createCallbackFcn(app, @X_VelSliderValueChanged, true);
            app.q1SliderLabel = uilabel(app.UIFigure);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.UIFigure);
            app.q1Slider.Limits = [-180 180];
            app.q1Slider.MajorTicks = [-180 0 180];
            app.q1Slider.ValueChangedFcn = createCallbackFcn(app, @q1SliderValueChanged, true);
            app.ModeButtonGroup = uibuttongroup(app.UIFigure);
            app.ModeButtonGroup.Title = 'Mode';
            app.Adv_TeachButton = uitogglebutton(app.ModeButtonGroup);
            app.Adv_TeachButton.Text = 'Adv_Teach';
            app.Adv_TeachButton.Value = true;
            app.SortRedButton = uitogglebutton(app.ModeButtonGroup);
            app.SortRedButton.Text = 'Sort Red';
            app.SortGreenButton = uitogglebutton(app.ModeButtonGroup);
            app.SortGreenButton.Text = 'Sort Green';
            app.SortBlueButton = uitogglebutton(app.ModeButtonGroup);
            app.SortBlueButton.Text = 'Sort Blue';
            app.SortAllButton = uitogglebutton(app.ModeButtonGroup);
            app.SortAllButton.Text = 'Sort All';
            app.VisualServoButton = uitogglebutton(app.ModeButtonGroup);
            app.VisualServoButton.Text = 'Visual Servo';
            app.q2SliderLabel = uilabel(app.UIFigure);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.UIFigure);
            app.q2Slider.Limits = [-180 180];
            app.q2Slider.MajorTicks = [-180 0 180];
            app.q2Slider.ValueChangedFcn = createCallbackFcn(app, @q2SliderValueChanged, true);
            app.q3SliderLabel = uilabel(app.UIFigure);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.UIFigure);
            app.q3Slider.Limits = [-180 180];
            app.q3Slider.MajorTicks = [-180 0 180];
            app.q3Slider.ValueChangedFcn = createCallbackFcn(app, @q3SliderValueChanged, true);
            app.q5SliderLabel = uilabel(app.UIFigure);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.UIFigure);
            app.q5Slider.Limits = [-180 180];
            app.q5Slider.MajorTicks = [-180 0 180];
            app.q5Slider.ValueChangedFcn = createCallbackFcn(app, @q5SliderValueChanged, true);
            app.Y_VelSliderLabel = uilabel(app.UIFigure);
            app.Y_VelSliderLabel.Text = 'Y_Vel';
            app.Y_VelSlider = uislider(app.UIFigure);
            app.Y_VelSlider.Limits = [-2 2];
            app.Y_VelSlider.MajorTicks = [-2 -1 0 1 2];
            app.Y_VelSlider.ValueChangedFcn = createCallbackFcn(app, @Y_VelSliderValueChanged, true);
            app.Z_VelSliderLabel = uilabel(app.UIFigure);
            app.Z_VelSliderLabel.Text = 'Z_Vel';
            app.Z_VelSlider = uislider(app.UIFigure);
            app.Z_VelSlider.Limits = [-2 2];
            app.Z_VelSlider.MajorTicks = [-2 -1 0 1 2];
            app.Z_VelSlider.ValueChangedFcn = createCallbackFcn(app, @Z_VelSliderValueChanged, true);
            app.Z_Rot_VelSliderLabel = uilabel(app.UIFigure);
            app.Z_Rot_VelSliderLabel.Text = 'Z_Rot_Vel';
            app.Z_Rot_VelSlider = uislider(app.UIFigure);
            app.Z_Rot_VelSlider.Limits = [-2 2];
            app.Z_Rot_VelSlider.MajorTicks = [-2 -1 0 1 2];
            app.Z_Rot_VelSlider.ValueChangedFcn = createCallbackFcn(app, @Z_Rot_VelSliderValueChanged, true);
            app.AddObsticalButton = uibutton(app.UIFigure, 'push');
            app.AddObsticalButton.Text = 'Add Obstical';
            app.ActivateCameraButton = uibutton(app.UIFigure, 'push');
            app.ActivateCameraButton.Text = 'Activate Camera';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.Text = 'Pause';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner_2 = uispinner(app.UIFigure);
            app.Spinner_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_2ValueChanged, true);
            app.Spinner_3 = uispinner(app.UIFigure);
            app.Spinner_3.ValueChangedFcn = createCallbackFcn(app, @Spinner_3ValueChanged, true);
            app.Spinner_4 = uispinner(app.UIFigure);
            app.Spinner_4.ValueChangedFcn = createCallbackFcn(app, @Spinner_4ValueChanged, true);
            app.Spinner_5 = uispinner(app.UIFigure);
            app.Spinner_5.ValueChangedFcn = createCallbackFcn(app, @Spinner_5ValueChanged, true);
            app.Spinner_6 = uispinner(app.UIFigure);
            app.Spinner_6.ValueChangedFcn = createCallbackFcn(app, @Spinner_6ValueChanged, true);
            app.Spinner_7 = uispinner(app.UIFigure);
            app.Spinner_7.ValueChangedFcn = createCallbackFcn(app, @Spinner_7ValueChanged, true);
            app.Spinner_8 = uispinner(app.UIFigure);
            app.Spinner_8.ValueChangedFcn = createCallbackFcn(app, @Spinner_8ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end